classdef mySinGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GraphDropDown                  matlab.ui.control.DropDown
        GraphDropDownLabel             matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        ChangecolorvaluestoButton      matlab.ui.control.Button
        BlueSlider                     matlab.ui.control.Slider
        BlueSliderLabel                matlab.ui.control.Label
        GreenSlider                    matlab.ui.control.Slider
        GreenSliderLabel               matlab.ui.control.Label
        RedSlider                      matlab.ui.control.Slider
        RedSliderLabel                 matlab.ui.control.Label
        ChooseaxistochangecolorsLabel  matlab.ui.control.Label
        AxisDropDown                   matlab.ui.control.DropDown
        AxisDropDownLabel              matlab.ui.control.Label
        UpperlimitEditField            matlab.ui.control.NumericEditField
        UpperlimitEditFieldLabel       matlab.ui.control.Label
        PrecisiondegreeSlider          matlab.ui.control.Slider
        BottomlimitEditField           matlab.ui.control.EditField
        BottomlimitEditFieldLabel      matlab.ui.control.Label
        PrecisiondegreeSliderLabel     matlab.ui.control.Label
        LinethicknessSlider            matlab.ui.control.Slider
        LinethicknessSliderLabel       matlab.ui.control.Label
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes1                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        bottom_limit = -5;
        upper_limit = 5; 
        step = 0.1; %domain step
        precision = 5; %mySin Taylor expansion precision
        x; %domain
        axis; %array of axes
        chosen_axis = 1; %chosen axis
        chosen_graph = 1; %graph chosen on chosen axis
        p; %array of plot objects
    end
    
    methods (Access = private)

        function changeLimits(app) %changes XData and YData of graphs to match new domain
            for i = 1:8
                set(app.p(i), 'XData', app.x);
            end
            set(app.p(1), 'YData', app.mySin(app.x, app.precision));
            set(app.p(2), 'YData', sin(app.x));

            set(app.p(3), 'YData', app.mySin(app.x, 2 * app.precision));
            set(app.p(4), 'YData', sin(app.x));

            set(app.p(5), 'YData', app.absoluteError(app.mySin(app.x, app.precision), sin(app.x)));
            set(app.p(6), 'YData', app.absoluteError(app.mySin(app.x, 2 * app.precision), sin(app.x)));

            set(app.p(7), 'YData', app.relativeError(app.mySin(app.x, app.precision), sin(app.x)));
            set(app.p(8), 'YData', app.relativeError(app.mySin(app.x, 2 * app.precision), sin(app.x)));
        end

        function setLampColor(app) %changes lamp color(used in ColorSliderValueChanging)
            app.Lamp.Color = [app.RedSlider.Value app.GreenSlider.Value app.BlueSlider.Value] / 255;
        end

        function changePrecision(app) %changes YData of graphs to match new domain
            set(app.p(1), 'YData', app.mySin(app.x, app.precision));
            set(app.p(3), 'YData', app.mySin(app.x, 2 * app.precision));

            set(app.p(5), 'YData', app.absoluteError(app.mySin(app.x, app.precision), sin(app.x)));
            set(app.p(6), 'YData', app.absoluteError(app.mySin(app.x, 2 * app.precision), sin(app.x)));
            
            set(app.p(7), 'YData', app.relativeError(app.mySin(app.x, app.precision), sin(app.x)));
            set(app.p(8), 'YData', app.relativeError(app.mySin(app.x, 2 * app.precision), sin(app.x)));
        end

        function result =  mySin(app, x, precision) %calculates value of sin function using Taylor expansion
            result = x' * ones(1, 2*precision+1);
            result(:, 2:2:2*precision+1) = 0;
            result(:, 1:2:2*precision+1) = result(:, 1:2:2*precision+1) .^ (1:2:2*precision+1);
            result = result ./ cumprod(1 : 2*precision+1);
            result(:, 3:4:2*precision+1) = result(:, 3:4:2*precision+1) * (-1);
            result = result * ones(2*precision+1, 1);
            result = result';
        end

        function result = absoluteError(app, xApprox, x) %calculates value of absolute error
            result = abs(xApprox - x);
        end

        function result = relativeError(app, xApprox, x) %calculates value of relative error
            result = abs(xApprox - x) ./ (abs(x) + 1); %could use eps instead of 1, but this way
            %relative error is more visible                                     
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app)
            app.x = app.bottom_limit:app.step:app.upper_limit;
            app.axis = [app.UIAxes1 app.UIAxes2 app.UIAxes3 app.UIAxes4];
            app.setLampColor();
            
            %initializing plot objects in app.p
            hold(app.axis, "on");
            app.p(1) = plot(app.axis(1), app.x, app.mySin(app.x, app.precision));
            app.p(2) = plot(app.axis(1), app.x, sin(app.x));
            app.p(3) = plot(app.axis(2), app.x, app.mySin(app.x, 2 * app.precision));
            app.p(4) = plot(app.axis(2), app.x, sin(app.x));
            app.p(5) = plot(app.axis(3), app.x, app.absoluteError(app.mySin(app.x, app.precision), sin(app.x)));
            app.p(6) = plot(app.axis(3), app.x, app.absoluteError(app.mySin(app.x, 2 * app.precision), sin(app.x)));
            app.p(7) = plot(app.axis(4), app.x, app.relativeError(app.mySin(app.x, app.precision), sin(app.x)));
            app.p(8) = plot(app.axis(4), app.x, app.relativeError(app.mySin(app.x, 2 * app.precision), sin(app.x)));

            grid(app.axis, "on"); %looks nice
        end

        % Value changed function: UpperlimitEditField
        function UpperlimitEditFieldValueChanged(app, event)
            %changes upper_limit and app.x function, invokes app.changeLimits()
            app.upper_limit = app.UpperlimitEditField.Value;
            app.x = app.bottom_limit:app.step:app.upper_limit;
            app.changeLimits();
        end

        % Value changed function: BottomlimitEditField
        function BottomlimitEditFieldValueChanged(app, event)
            %changes app.bottom_limit and app.x, invokes app.changeLimits()
            app.bottom_limit = str2double(app.BottomlimitEditField.Value);
            app.x = app.bottom_limit:app.step:app.upper_limit;
            app.changeLimits();
        end

        % Value changed function: PrecisiondegreeSlider
        function PrecisiondegreeSliderValueChanged(app, event)
            %changes app.precision, invokes app.changePrecision()
            app.precision = floor(app.PrecisiondegreeSlider.Value);
            app.changePrecision();
        end

        % Value changed function: LinethicknessSlider
        function LinethicknessSliderValueChanged(app, event)
            %changes app.widtt and graphs width
            for i = 1:8
                set(app.p(i), 'LineWidth', app.LinethicknessSlider.Value);
            end
        end

        % Value changing function: BlueSlider, GreenSlider, RedSlider
        function ColorSliderValueChanging(app, event)
            %changes color of lamp in real time
            temp = [app.RedSlider.Value app.GreenSlider.Value app.BlueSlider.Value];
            
            if strcmp(event.Source.Tag, "red")
                temp(1) = event.Value;
            elseif strcmp(event.Source.Tag, "green")
                temp(2) = event.Value;    
            elseif strcmp(event.Source.Tag, "blue")
                temp(3) = event.Value;
            end

            app.Lamp.Color = temp / 255;
        end

        % Button pushed function: ChangecolorvaluestoButton
        function ChangecolorvaluestoButtonPushed(app, event)
            %changes chosen graph color to match the RGB sliders
            if strcmp(app.GraphDropDown.Value, '1')
                set(app.p(2 * app.chosen_axis - 1), 'Color', [app.RedSlider.Value app.GreenSlider.Value app.BlueSlider.Value] / 255);
            else
                set(app.p(2 * app.chosen_axis), 'Color', [app.RedSlider.Value app.GreenSlider.Value app.BlueSlider.Value] / 255)
            end
        end

        % Value changed function: AxisDropDown
        function AxisDropDownValueChanged(app, event)
            app.chosen_axis = str2double(app.AxisDropDown.Value);
        end

        % Value changed function: GraphDropDown
        function GraphDropDownValueChanged(app, event)
            app.chosen_graph = str2double(app.GraphDropDown.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Sinus-more precise')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Relative Error')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'Sinus-less precise')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Absolute Error')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.LinethicknessSliderLabel = uilabel(app.UIFigure);
            app.LinethicknessSliderLabel.Text = {'Line thickness'; ''};
            app.LinethicknessSlider = uislider(app.UIFigure);
            app.LinethicknessSlider.Limits = [1 5];
            app.LinethicknessSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.LinethicknessSlider.ValueChangedFcn = createCallbackFcn(app, @LinethicknessSliderValueChanged, true);
            app.LinethicknessSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.LinethicknessSlider.Value = 1;
            app.PrecisiondegreeSliderLabel = uilabel(app.UIFigure);
            app.PrecisiondegreeSliderLabel.Text = {'Precision degree'; ''};
            app.BottomlimitEditFieldLabel = uilabel(app.UIFigure);
            app.BottomlimitEditFieldLabel.Text = 'Bottom limit';
            app.BottomlimitEditField = uieditfield(app.UIFigure, 'text');
            app.BottomlimitEditField.ValueChangedFcn = createCallbackFcn(app, @BottomlimitEditFieldValueChanged, true);
            app.BottomlimitEditField.Placeholder = '5';
            app.BottomlimitEditField.Value = '-5';
            app.PrecisiondegreeSlider = uislider(app.UIFigure);
            app.PrecisiondegreeSlider.Limits = [1 21];
            app.PrecisiondegreeSlider.ValueChangedFcn = createCallbackFcn(app, @PrecisiondegreeSliderValueChanged, true);
            app.PrecisiondegreeSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21];
            app.PrecisiondegreeSlider.Value = 5;
            app.UpperlimitEditFieldLabel = uilabel(app.UIFigure);
            app.UpperlimitEditFieldLabel.Text = 'Upper limit';
            app.UpperlimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperlimitEditField.ValueChangedFcn = createCallbackFcn(app, @UpperlimitEditFieldValueChanged, true);
            app.UpperlimitEditField.Value = 5;
            app.AxisDropDownLabel = uilabel(app.UIFigure);
            app.AxisDropDownLabel.Text = 'Axis';
            app.AxisDropDown = uidropdown(app.UIFigure);
            app.AxisDropDown.Items = {'Sinus-less precise', 'Sinus-more precise', 'Absolute Error', 'Relative Error'};
            app.AxisDropDown.ItemsData = {'1', '2', '3', '4'};
            app.AxisDropDown.ValueChangedFcn = createCallbackFcn(app, @AxisDropDownValueChanged, true);
            app.AxisDropDown.Value = '1';
            app.ChooseaxistochangecolorsLabel = uilabel(app.UIFigure);
            app.ChooseaxistochangecolorsLabel.Text = 'Choose axis to change colors';
            app.RedSliderLabel = uilabel(app.UIFigure);
            app.RedSliderLabel.Text = 'Red';
            app.RedSlider = uislider(app.UIFigure);
            app.RedSlider.Limits = [0 255];
            app.RedSlider.ValueChangingFcn = createCallbackFcn(app, @ColorSliderValueChanging, true);
            app.RedSlider.Tag = 'red';
            app.RedSlider.Value = 65;
            app.GreenSliderLabel = uilabel(app.UIFigure);
            app.GreenSliderLabel.Text = 'Green';
            app.GreenSlider = uislider(app.UIFigure);
            app.GreenSlider.Limits = [0 255];
            app.GreenSlider.ValueChangingFcn = createCallbackFcn(app, @ColorSliderValueChanging, true);
            app.GreenSlider.Tag = 'green';
            app.GreenSlider.Value = 105;
            app.BlueSliderLabel = uilabel(app.UIFigure);
            app.BlueSliderLabel.Text = 'Blue';
            app.BlueSlider = uislider(app.UIFigure);
            app.BlueSlider.Limits = [0 255];
            app.BlueSlider.ValueChangingFcn = createCallbackFcn(app, @ColorSliderValueChanging, true);
            app.BlueSlider.Tag = 'blue';
            app.BlueSlider.Value = 225;
            app.ChangecolorvaluestoButton = uibutton(app.UIFigure, 'push');
            app.ChangecolorvaluestoButton.ButtonPushedFcn = createCallbackFcn(app, @ChangecolorvaluestoButtonPushed, true);
            app.ChangecolorvaluestoButton.Text = 'Change color values to';
            app.Lamp = uilamp(app.UIFigure);
            app.GraphDropDownLabel = uilabel(app.UIFigure);
            app.GraphDropDownLabel.Text = 'Graph';
            app.GraphDropDown = uidropdown(app.UIFigure);
            app.GraphDropDown.Items = {'First graph', 'Second graph'};
            app.GraphDropDown.ItemsData = {'1', '2'};
            app.GraphDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphDropDownValueChanged, true);
            app.GraphDropDown.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mySinGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @init)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end