classdef ruch_srubowy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Lamp                            matlab.ui.control.Lamp
        ChangecolortoButton             matlab.ui.control.Button
        BlueSlider                      matlab.ui.control.Slider
        BlueSliderLabel                 matlab.ui.control.Label
        GreenSlider                     matlab.ui.control.Slider
        GreenSliderLabel                matlab.ui.control.Label
        RedSlider                       matlab.ui.control.Slider
        RedSliderLabel                  matlab.ui.control.Label
        ChoosepointforcolorchangeLabel  matlab.ui.control.Label
        PointDropDown                   matlab.ui.control.DropDown
        PointDropDownLabel              matlab.ui.control.Label
        RadiusofsmallcircleSlider       matlab.ui.control.Slider
        RadiusofsmallcircleSliderLabel  matlab.ui.control.Label
        RadiusofbigcircleSlider         matlab.ui.control.Slider
        RadiusofbigcircleSliderLabel    matlab.ui.control.Label
        AnimationdurationEditField      matlab.ui.control.NumericEditField
        AnimationdurationEditFieldLabel  matlab.ui.control.Label
        StartanimationButton            matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        limit = 10; %limity osi do skalowania
        modifier = .4; %skalowanie kuli
        radius = 3; %promien malego okregu
        radiusBig = 10; %promien duzego okregu
        duration = 500; %czas trwania animacji
        s; %tablica obiektów klasy surf
        X, Y, Z;
        colors = [65 65 65];
    end
    
    methods (Access = private)
        
        function setAxes(app)
            axis(app.UIAxes, [-2*app.limit 2*app.limit -app.limit app.limit -1.5*app.limit 1.5*app.limit]);
        end
        
        function setStartPos(app)
            set(app.s(1), 'XData', app.X + app.radiusBig);
            set(app.s(1), 'YData', app.Y - app.radius);
            set(app.s(1), 'ZData', app.Z);
            set(app.s(2), 'XData', app.X + app.radiusBig);
            set(app.s(2), 'YData', app.Y + app.radius);
            set(app.s(2), 'ZData', app.Z);
        end
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app)
            [app.X, app.Y, app.Z] = sphere(20);
            app.X = app.X * app.modifier;
            app.Y = app.Y * app.modifier;
            app.Z = app.Z * app.modifier;
            
            hold(app.UIAxes, "on");
            axis(app.UIAxes, "equal");
            campos(app.UIAxes, [90 90 90]);

            app.s(1) = surf(app.UIAxes, app.X+app.radiusBig, app.Y-app.radius, app.Z, EdgeColor="none", FaceColor="b");
            app.s(2) = surf(app.UIAxes, app.X+app.radiusBig, app.Y+app.radius, app.Z, EdgeColor="none", FaceColor="r");
            app.setAxes();
            
            
        end

        % Button pushed function: StartanimationButton
        function StartanimationButtonPushed(app, event)
            app.setStartPos();
            for i = 1:app.duration
                set(app.s(1), 'XData', (app.X + app.radiusBig + app.radius * cos(.1 * i)) * cos(.01 * i) + app.Z * sin(.01 * i));
                set(app.s(1), 'YData', (app.Y + app.radius * sin(.1 * i)));
                set(app.s(1), 'ZData', (-(app.X+ app.radiusBig + app.radius * cos(.1 * i)) * sin(.01 * i) + app.Z * cos(.01 * i)));
                
                set(app.s(2), 'XData', (app.X + app.radiusBig + app.radius * cos(pi + .1 * i)) * cos(.01 * i) + app.Z * sin(.01 * i));
                set(app.s(2), 'YData', (app.Y + app.radius * sin(pi + .1 * i)));
                set(app.s(2), 'ZData', (-(app.X+ app.radiusBig + app.radius * cos(pi + .1 * i)) * sin(.01 * i) + app.Z * cos(.01 * i)));
                
                pause(.01);
            end
        end

        % Value changed function: AnimationdurationEditField
        function AnimationdurationEditFieldValueChanged(app, event)
            app.duration = floor(100 * app.AnimationdurationEditField.Value);
        end

        % Value changed function: RadiusofbigcircleSlider
        function RadiusofbigcircleSliderValueChanged(app, event)
            app.radiusBig = app.RadiusofbigcircleSlider.Value;
            app.limit = 0.75*(app.radiusBig + app.radius);
            app.setAxes();
            app.setStartPos();
        end

        % Value changed function: RadiusofsmallcircleSlider
        function RadiusofsmallcircleSliderValueChanged(app, event)
            app.radius = app.RadiusofsmallcircleSlider.Value;
            app.limit = 0.75*(app.radiusBig + app.radius);
            app.setAxes();
            app.setStartPos();
        end

        % Value changing function: BlueSlider, GreenSlider, RedSlider
        function ColorSliderValueChanging(app, event)
            if strcmp(event.Source.Tag, "red")
                app.colors(1) = event.Value;
            elseif strcmp(event.Source.Tag, "green")
                app.colors(2) = event.Value;    
            elseif strcmp(event.Source.Tag, "blue")
                app.colors(3) = event.Value;
            end
            
            app.Lamp.Color = app.colors / 255;

        end

        % Button pushed function: ChangecolortoButton
        function ChangecolortoButtonPushed(app, event)
            set(app.s(app.PointDropDown.Value), 'FaceColor', app.colors / 255);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Spiral Movement')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StartanimationButton = uibutton(app.UIFigure, 'push');
            app.StartanimationButton.ButtonPushedFcn = createCallbackFcn(app, @StartanimationButtonPushed, true);
            app.StartanimationButton.Text = 'Start animation';
            app.AnimationdurationEditFieldLabel = uilabel(app.UIFigure);
            app.AnimationdurationEditFieldLabel.Text = {'Animation duration'; ''};
            app.AnimationdurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnimationdurationEditField.Limits = [0 20];
            app.AnimationdurationEditField.ValueChangedFcn = createCallbackFcn(app, @AnimationdurationEditFieldValueChanged, true);
            app.AnimationdurationEditField.Value = 5;
            app.RadiusofbigcircleSliderLabel = uilabel(app.UIFigure);
            app.RadiusofbigcircleSliderLabel.Text = 'Radius of big circle';
            app.RadiusofbigcircleSlider = uislider(app.UIFigure);
            app.RadiusofbigcircleSlider.Limits = [5 20];
            app.RadiusofbigcircleSlider.MajorTicks = [5 10 15 20];
            app.RadiusofbigcircleSlider.ValueChangedFcn = createCallbackFcn(app, @RadiusofbigcircleSliderValueChanged, true);
            app.RadiusofbigcircleSlider.Value = 10;
            app.RadiusofsmallcircleSliderLabel = uilabel(app.UIFigure);
            app.RadiusofsmallcircleSliderLabel.Text = 'Radius of small circle';
            app.RadiusofsmallcircleSlider = uislider(app.UIFigure);
            app.RadiusofsmallcircleSlider.Limits = [0 10];
            app.RadiusofsmallcircleSlider.MajorTicks = [0 2 4 6 8 10];
            app.RadiusofsmallcircleSlider.MajorTickLabels = {'0', '2', '4', '6', '8', '10', ''};
            app.RadiusofsmallcircleSlider.ValueChangedFcn = createCallbackFcn(app, @RadiusofsmallcircleSliderValueChanged, true);
            app.RadiusofsmallcircleSlider.Value = 3;
            app.PointDropDownLabel = uilabel(app.UIFigure);
            app.PointDropDownLabel.Text = 'Point';
            app.PointDropDown = uidropdown(app.UIFigure);
            app.PointDropDown.Items = {'First point', 'Second point'};
            app.PointDropDown.ItemsData = [1 2];
            app.PointDropDown.Value = 1;
            app.ChoosepointforcolorchangeLabel = uilabel(app.UIFigure);
            app.ChoosepointforcolorchangeLabel.Text = 'Choose point for color change';
            app.RedSliderLabel = uilabel(app.UIFigure);
            app.RedSliderLabel.Text = 'Red';
            app.RedSlider = uislider(app.UIFigure);
            app.RedSlider.Limits = [0 255];
            app.RedSlider.ValueChangingFcn = createCallbackFcn(app, @ColorSliderValueChanging, true);
            app.RedSlider.Tag = 'red';
            app.RedSlider.Value = 65;
            app.GreenSliderLabel = uilabel(app.UIFigure);
            app.GreenSliderLabel.Text = 'Green';
            app.GreenSlider = uislider(app.UIFigure);
            app.GreenSlider.Limits = [0 255];
            app.GreenSlider.ValueChangingFcn = createCallbackFcn(app, @ColorSliderValueChanging, true);
            app.GreenSlider.Tag = 'green';
            app.GreenSlider.Value = 65;
            app.BlueSliderLabel = uilabel(app.UIFigure);
            app.BlueSliderLabel.Text = 'Blue';
            app.BlueSlider = uislider(app.UIFigure);
            app.BlueSlider.Limits = [0 255];
            app.BlueSlider.ValueChangingFcn = createCallbackFcn(app, @ColorSliderValueChanging, true);
            app.BlueSlider.Tag = 'blue';
            app.BlueSlider.Value = 65;
            app.ChangecolortoButton = uibutton(app.UIFigure, 'push');
            app.ChangecolortoButton.ButtonPushedFcn = createCallbackFcn(app, @ChangecolortoButtonPushed, true);
            app.ChangecolortoButton.Text = 'Change color to';
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ruch_srubowy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @init)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end